classdef FoerstnerApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        between01Label_2                matlab.ui.control.Label
        greaterthan0Label               matlab.ui.control.Label
        eg57or21Label                   matlab.ui.control.Label
        RunLabel                        matlab.ui.control.Label
        SetParametersLabel              matlab.ui.control.Label
        LoadimagejpgLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DevelopedbyYakubuAlhassanLabel  matlab.ui.control.Label
        FrstnerOperatorLabel            matlab.ui.control.Label
        RoundThresholdEditField         matlab.ui.control.EditField
        RoundThresholdEditFieldLabel    matlab.ui.control.Label
        WeightThresholdEditField        matlab.ui.control.EditField
        WeightThresholdEditFieldLabel   matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        uploadImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %upload image

            [file,path,indx] = uigetfile;
            filename = [path file];
            app.uploadImage = importdata(filename);
            %orig_img = imread('C:\Users\Alhassan\Desktop\FOLDERS\MPG\SEM2\ATP\PH\image2.jpg');
            imshow(app.uploadImage, 'Parent',app.UIAxes);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Call uploaded image and convert to grayscale

            orig_img = app.uploadImage;
            %orig_img = imread('C:\Users\Alhassan\Desktop\FOLDERS\MPG\SEM2\ATP\PH\image2.jpg');    
            Gray_img = im2gray(orig_img);  

            %figure(2);
                %imshow(Gray_img);
         
            %Get values from input fields 
            win_s = app.WindowSizeEditField.Value           
            win_size = str2double(win_s)
            weight_th = app.WeightThresholdEditField.Value
            weight_int = str2double(weight_th)
            threshold = app.RoundThresholdEditField.Value
            threshold_int = str2double(threshold)
            
            %Get row, column heights
            row_height = size(Gray_img, 1);
            col_width = size(Gray_img, 2);
            win_border = floor(win_size/2);
            
            %Compute gradients using sobel operator (2 convolutions)
            gx = edge(Gray_img,'sobel','vertical');
            gy = edge(Gray_img,'sobel','horizontal');
            
            
            %Creating zero matrices to hold roundness and weight values
            round_mat = zeros(row_height, col_width);
            weight_mat = zeros(row_height, col_width);

            %Compute the gradient square and the multiple of the gradients
            gx2 = gx.*gx;
            gy2 = gy.*gy;
            gxgy_ = gx.*gy;
            
            %figure;
                  %imshow(gx2)
            %figure;
                 %imshow(gy2)
            %figure;
                 %imshow(gxgy_)
            
            figure;
            imshow(orig_img),title('Selected Points')

            
            for i = 1:row_height
                for j = 1:col_width
                        %Initialize threshold with 0
                        errell_round(i,j) = 0;                     
                        errell_weight(i,j) = 0;
                    
                        %Create box filter 
                    if (((i >= win_border + 1) && (i <= (row_height-win_border))) && (j >= (win_border + 1) && (j <= (col_width-win_border))))
                        
                        %Sum all pixel elements within the box filter for the gx2 gradient image and multiply by the gradient image gx
                        conv_x_values = gx2(i - win_border: i + win_border, j- win_border: j + win_border);  
                        conv_x_sum = sum(sum(conv_x_values));
                        
                        %Sum all pixel elements within the box filter for the gy2 gradient image and multiply by the gradient image gy square
                        conv_y_values = gy2(i - win_border: i + win_border, j- win_border: j + win_border);  
                        conv_y_sum = sum(sum(conv_y_values));
                        
                        %Sum all pixel elements within the box filter for the gxgy gradient image and multiply by the gradient image gxgy
                        conv_xy_values = gxgy_(i - win_border: i + win_border, j- win_border: j + win_border);  
                        conv_xy_sum = sum(sum(conv_xy_values));
                        
                        % Normal Matrix with elements sum of gx2, sum of gy2 and sum of gxgy
                        N_matrix = [conv_x_sum, conv_xy_sum; conv_xy_sum, conv_y_sum];

                        %determine eigen values of normal matrix and use them to compute the roundness and weight
                        eigenvalues = eig(N_matrix);
                        lambda1 = eigenvalues(1);
                        lambda2 = eigenvalues(2);

                        errell_round(i,j) = (lambda1*lambda2)/(lambda1+lambda2);
                        errell_weight(i,j) = 1-((lambda1-lambda2)/(lambda1+lambda2))^2;
                        
                        
                        % compare threshold to specified values
                        if (errell_weight(i,j) > weight_int && errell_round(i,j) > threshold_int)
                            
                        hold on;
                        plot(j,i,'r+','MarkerSize',0.3)  
                        end
                    end
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'Columns')
            ylabel(app.UIAxes, 'Rows')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.UIFigure, 'text');
            app.WeightThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.WeightThresholdEditFieldLabel.Text = 'Weight Threshold';
            app.WeightThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.RoundThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.RoundThresholdEditFieldLabel.Text = 'Round Threshold';
            app.RoundThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.FrstnerOperatorLabel = uilabel(app.UIFigure);
            app.FrstnerOperatorLabel.Text = 'Förstner Operator';
            app.DevelopedbyYakubuAlhassanLabel = uilabel(app.UIFigure);
            app.DevelopedbyYakubuAlhassanLabel.Text = 'Developed by: Yakubu Alhassan';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'hftlogo.png';
            app.LoadimagejpgLabel = uilabel(app.UIFigure);
            app.LoadimagejpgLabel.Text = '1. Load image (jpg)';
            app.SetParametersLabel = uilabel(app.UIFigure);
            app.SetParametersLabel.Text = '2. Set Parameters';
            app.RunLabel = uilabel(app.UIFigure);
            app.RunLabel.Text = '3. Run';
            app.eg57or21Label = uilabel(app.UIFigure);
            app.eg57or21Label.Text = 'eg. 5,7 or 21';
            app.greaterthan0Label = uilabel(app.UIFigure);
            app.greaterthan0Label.Text = 'greater than 0';
            app.between01Label_2 = uilabel(app.UIFigure);
            app.between01Label_2.Text = 'between 0 & 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FoerstnerApp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end